classdef TaesTool < matlab.apps.AppBase
    %TAESTOOL Thermoeconomic Analysis of Energy Systems
    %   TaesApp makes thermoeconomic analysis and diagnosis of energy
    %   systems.
    %   - In the left panel, the data model and parameters could be
    %   selected
    %   - In the right panel, select the results to view in the central
    %   panel as tables or graphs
    %   - Results could be saved in different formats

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveResultsMenu               matlab.ui.container.Menu
        SaveDataModelMenu             matlab.ui.container.Menu
        SaveSummaryMenu               matlab.ui.container.Menu
        SaveDiagramMenu               matlab.ui.container.Menu
        SaveModelMenu                 matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        ResultsMenu                   matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        GridLayout                    matlab.ui.container.GridLayout
        LogPanel                      matlab.ui.container.Panel
        LogField                      matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
        TableIndexPanel               matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        InputParametersPanel          matlab.ui.container.Panel
        InputGridLayout               matlab.ui.container.GridLayout
        ViewTableDropDownLabel        matlab.ui.control.Label
        ReferenceStateDropDown        matlab.ui.control.DropDown
        ReferenceStateDropDownLabel   matlab.ui.control.Label
        DiagnosisMethodDropDown       matlab.ui.control.DropDown
        DiagnosisMethodDropDownLabel  matlab.ui.control.Label
        CostTablesDropDown            matlab.ui.control.DropDown
        CostTablesDropDownLabel       matlab.ui.control.Label
        ResourceCostDropDown          matlab.ui.control.DropDown
        ResourceCostDropDownLabel     matlab.ui.control.Label
        OperationStateDropDown        matlab.ui.control.DropDown
        OperationStateDropDownLabel   matlab.ui.control.Label
        DataModelEditField            matlab.ui.control.EditField
        DataModelEditFieldLabel       matlab.ui.control.Label
        ActiveWasteDropDownLabel      matlab.ui.control.Label
        ActiveWasteDropDown           matlab.ui.control.DropDown
        RecyclingAnalysisLabel        matlab.ui.control.Label
        RecyclingCheckBox             matlab.ui.control.CheckBox
        SummaryResultsLabel           matlab.ui.control.Label
        SummaryCheckBox               matlab.ui.control.CheckBox
        ResultsFileLabel              matlab.ui.control.Label
        OutputFileEditField           matlab.ui.control.EditField
        ViewTableDropDown             matlab.ui.control.DropDown
        LoadDataButton                matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        Model   % Thermoeconomic Model
    end
    
    properties (Access = private)
        ResultFile  % Result file name
        LogColors   % Color Log definition
        CurrentNode % Selected group of tables
        Messages    % App messages logger
        TableIndex  % Current Table Index object
        isActive    % Results are Active
        viewOption  % View Option
        menu
        tb
    end
    
    methods (Access = private)
        function InitInputProperties(app)
            % Initialize Input parameters
            app.DataModelEditField.BackgroundColor=[1 0.5 0.5];
            app.DataModelEditField.Value='No Model Available';
            app.SaveButton.Enable=false;
			app.CostTablesDropDown.Enable=false;
            app.CostTablesDropDown.Value=cType.DEFAULT_COST_TABLES;
            app.DiagnosisMethodDropDown.Enable=false;
            app.DiagnosisMethodDropDown.Items={cType.DEFAULT_DIAGNOSIS};
			app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
            app.ReferenceStateDropDown.Enable=false;
            app.ReferenceStateDropDown.Items={'Reference','Operation'};
            app.ReferenceStateDropDown.Value='Reference';
            app.OperationStateDropDown.Enable=false;
            app.OperationStateDropDown.Items={'Reference','Operation'};
            app.OperationStateDropDown.Value='Reference';
            app.ResourceCostDropDown.Enable=false;
            app.ResourceCostDropDown.Items={'Base'};
            app.ResourceCostDropDown.Value='Base';
            app.SummaryCheckBox.Enable=false;
            app.SummaryCheckBox.Value=false;
            app.ViewTableDropDown.Enable=true;
            app.viewOption=cType.TableView.CONSOLE;
            app.LogField.Text='';
            app.Model=cStatusLogger;
            app.UITable.Visible=false;
            app.CurrentNode=[];
        end

        function InitTableIndex(app)
            td=cTablesDefinition;
            tbl=td.getTablesDirectory({'DESCRIPTION','GRAPH'});
            app.UITable.ColumnWidth={'auto','6x','1x'};
            app.UITable.Data=[tbl.RowNames',tbl.Data];
            app.UITable.Visible=true;
        end

        % Show the index table in table panel
        function ViewIndexTable(app,res)
            app.TableIndexPanel.Title=res.ResultName;
            tbl=res.getTableIndex;
            app.UITable.ColumnWidth={'auto','6x','1x'};
            app.UITable.Data=[tbl.RowNames',tbl.Data];
            app.UITable.Visible=true;
            app.TableIndex=tbl;
            app.CurrentNode=res;
        end

        
        function buildMenuToolbar(app)
            app.menu=cell(1,cType.MAX_RESULT_INFO);
            app.tb=cell(1,cType.MAX_RESULT_INFO);
            for i=1:cType.MAX_RESULT_INFO
                app.menu{i}=uimenu(app.ResultsMenu,...
                    'Text',cType.Results{i},...
                    'UserData',i,'Enable','off',...
                    'MenuSelectedFcn', @(src,evt) app.getResult(src,evt));
            end
            for i=1:cType.MAX_RESULT_INFO
                app.tb{i}=uipushtool (app.Toolbar,...
                    'CData',cType.getIcon(i),...,...
                    'UserData',i,'Enable','off',...
                    'Tooltip',cType.Results{i},...
                    'ClickedCallback', @(src,evt) app.getResult(src,evt));
            end
        end

        function ActiveResults(app,idx,value)
            app.menu{idx}.Enable=value;
            app.tb{idx}.Enable=value;
        end

        function getResult(app,src,~)
            idx=src.UserData;
            fprintf('ResulId: %d',idx);
            res=app.Model.getResults(idx);
            if ~isempty(res)
                app.ViewIndexTable(res);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tables=cType.CostTablesOptions();
            app.CostTablesDropDown.Items=tables;
            outputFileName=strcat(cType.RESULT_FILE,'.xlsx');
            app.OutputFileEditField.Value=outputFileName;
			app.ResultFile=[pwd,filesep,outputFileName];
            app.buildMenuToolbar;
            app.InitInputProperties;
            InitTableIndex(app)
        end

        % Callback function: LoadDataButton, OpenMenu
        function LoadData(app, event)
            % Load datafile
            app.InitInputProperties;
            [file,path]=uigetfile({'*.json;*.xml*;.csv;*.xlsx;*.mat','Suported Data Models'});
            if file
			    cd(path);
			    app.DataModelEditField.Value=file;
            else
                logtext=' ERROR: No Model Available';
                app.LogField.Text=logtext; 
                return
            end
			% Read and Check Data Model
            data=checkDataModel(file);
            % Assing parameters and execute base analysis
            if isValid(data) %Assign parameters
                app.Model=cThermoeconomicModel(data);
                app.DataModelEditField.BackgroundColor=[0.95 1 0.95];
                app.SaveButton.Enable=true;
                app.SaveResultsMenu.Enable=true;
                app.SaveDataModelMenu.Enable=true;
                app.SaveDiagramMenu.Enable=true;
                app.SaveModelMenu.Enable=true;
				stateNames=app.Model.StateNames;
                app.OperationStateDropDown.Enable=true;
                app.OperationStateDropDown.Items=stateNames;
                app.ReferenceStateDropDown.Enable=true;
                app.ReferenceStateDropDown.Items=stateNames;
                %app.ViewTableDropDown.Enable=true;
                if data.NrOfStates>1
                    app.SummaryCheckBox.Enable=true;
                end
                if data.isResourceCost
					sampleNames=app.Model.SampleNames;
                    app.ResourceCostDropDown.Items=sampleNames;
                    app.ResourceCostDropDown.Enable=true;
                    app.CostTablesDropDown.Enable=true;
                    app.CostTablesDropDown.Value=cType.DEFAULT_COST_TABLES;
                end
                dnames=cType.DiagnosisOptions;
                if data.isWaste
                    wf=app.Model.WasteFlows;
					app.DiagnosisMethodDropDown.Items=dnames;
            		app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
                    app.ActiveWasteDropDown.Items=wf;
                    app.ActiveWasteDropDown.Enable=true;
                    app.RecyclingCheckBox.Enable=true;
                else
				    app.DiagnosisMethodDropDown.Items=dnames(1:2);
            		app.DiagnosisMethodDropDown.Value=cType.DEFAULT_DIAGNOSIS;
                end
                logtext=' INFO: Valid Data Model';
                app.ActiveResults(cType.ResultId.PRODUCTIVE_STRUCTURE,true);
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_STATE,true);
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_ANALYSIS,true);
                app.ActiveResults(cType.ResultId.WASTE_ANALYSIS,true);
                app.ActiveResults(cType.ResultId.DATA_MODEL,true);           
                app.ActiveResults(cType.ResultId.RESULT_MODEL,true);
                res=app.Model.resultModelInfo;
                app.CurrentNode=res;
                app.ViewIndexTable(res);
            else
                app.DataModelEditField.BackgroundColor=[1 0.5 0.5];
				logtext=' ERROR: Invalid Data Model';
                app.Model.addLogger(data);
                uialert(app.UIFigure,logtext,'Warning');
            end
            app.LogField.Text=logtext;

        end

        % Menu selected function: SaveModelMenu
        function SaveModel(app, event)
           % Save the model in the workspace
            if ~isdeployed
                assignin('base', 'model', app.Model);
                app.LogField.Text='INFO: Model saved on workspace';
            end
        end

        % Callback function: SaveButton, SaveResultsMenu
        function SaveResults(app, event)
            % Save the model results
            log=cStatusLogger;
            default_file=strcat(cType.RESULT_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.txt','TXT Files'; ...
                '*.csv','CSV Files';'*.html','HTML Files';'*.tex','LaTeX Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path)
				filename=fullfile(path,file);
                log=saveResults(app.Model,file);
                if isvalid(log)
				    app.ResultFile=filename;
                    app.OutputFileEditField.Value=file;
                    logtext=sprintf(' INFO: Results Available in file %s',file);
                else
                    log.messageLog(cType.WARNING,'Results have NOT saved. ')
                    logtext=sprintf(' WARNING: Results have NOT saved. See Log');
                end
		    else
				logtext=sprintf(' WARNING: Results are NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);
        end

        % Menu selected function: SaveSummaryMenu
        function SaveSummary(app, event)
            % Save the summary results
            log=cStatusLogger;
            default_file=strcat(cType.SUMMARY_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.txt','TXT Files'; ...
                '*.csv','CSV Files';'*.html','HTML Files';'*.tex','LaTeX Files';'*.mat','MAT Files'},'Select File',default_file);
            
            if ext % File has been selected
                cd(path);
                log=saveSummary(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Summary Results Available in file %s',file);
                else
                    logtext=sprintf(' WARNING: Summary Results are NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Summary Results are NOT Available')
				logtext=sprintf(' WARNING: Summary Results are NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);  
        end

        % Menu selected function: SaveDiagramMenu
        function SaveDiagramFP(app, event)
            % Save the diagram FP
            log=cStatusLogger;
            default_file=strcat(cType.DIAGRAM_FILE,cType.FileExt.XLSX);
			[file,path,ext]=uiputfile({'*.xlsx','XLSX Files';'*.csv','CSV Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path);
                log=saveDiagramFP(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Summary Results in file %s',file);
                else
                    logtext=sprintf(' WARNING: Summary Results NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Summary Results are NOT Available')
				logtext=sprintf(' WARNING: Summary Results NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);    
        end

        % Menu selected function: SaveDataModelMenu
        function SaveDataModel(app, event)
            % Save the Data Model
            log=cStatusLogger;
            default_file=strcat(app.Model.ModelName,cType.FileExt.MAT);
            [file,path,ext]=uiputfile({'*.mat','MAT Files';'*.xlsx','XLSX Files';'*.txt','TXT Files'; ...
                '*.csv','CSV Files'},'Select File',default_file);
            if ext % File has been selected
                cd(path);
                log=saveDataModel(app.Model,file);
                if isValid(log)
			        logtext=sprintf(' INFO: Data Model Available in file %s',file);
                else
                    logtext=sprintf(' WARNING: Data Model is NOT saved. See Log');
                end
            else
                log.messageLog(cType.WARNING,'Data Model is NOT Available');
				logtext=sprintf(' WARNING: Data Model is NOT Available');
            end
            app.LogField.Text=logtext;
            app.ShowLogger(log);
        end

        % Callback function: CloseMenu, UIFigure
        function CloseApp(app, event)
            selection = uiconfirm(app.UIFigure,'Close the Application?',...
                'Confirmation');   
            switch selection
                case 'OK'
                    delete(app)                      
                case 'Cancel'
                    return
            end
        end

        % Value changed function: OperationStateDropDown
        function getOperationState(app, event)
            % Select Operation State
            app.Model.State = app.OperationStateDropDown.Value;
            app.DiagnosisMethodDropDown.Enable=app.Model.isDiagnosis;
            if app.Model.isDiagnosis
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,true);
            else
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,false);
            end
            app.ViewIndexTable(app.Model.thermoeconomicState);
        end

        % Value changed function: ReferenceStateDropDown
        function getReferenceState(app, event)
            % Select Reference State
            app.Model.ReferenceState=app.ReferenceStateDropDown.Value;
            app.DiagnosisMethodDropDown.Enable=app.Model.isDiagnosis;
            if app.Model.isDiagnosis
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,true);
                app.ViewIndexTable(app.Model.thermoeconomicDiagnosis);
            else
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,false);
            end
        end

        % Value changed function: CostTablesDropDown
        function getCostTables(app, event)
            % Select CostTable property 
            app.Model.CostTables = app.CostTablesDropDown.Value;
            app.ViewIndexTable(app.Model.thermoeconomicAnalysis);
        end

        % Value changed function: ResourceCostDropDown
        function getResourcesSample(app, event)
            % Select Resource Sample
            app.Model.ResourceSample = app.ResourceCostDropDown.Value;
            app.ViewIndexTable(app.Model.thermoeconomicAnalysis);
        end

        % Value changed function: DiagnosisMethodDropDown
        function getDiagnosisMethod(app, event)
            % Select DiagnosisMethod property
            app.Model.DiagnosisMethod = app.DiagnosisMethodDropDown.Value;
            if app.Model.isDiagnosis
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,true);
                app.ViewIndexTable(app.Model.thermoeconomicDiagnosis);
            else
                app.ActiveResults(cType.ResultId.THERMOECONOMIC_DIAGNOSIS,false);
            end
        end

        % Value changed function: ActiveWasteDropDown
        function getWasteFlow(app, event)
            app.Model.ActiveWaste = app.ActiveWasteDropDown.Value;
            app.ViewIndexTable(app.Model.thermoeconomicDiagnosis);
        end

        % Value changed function: RecyclingCheckBox
        function RecyclingValueChanged(app, event)
            value = app.RecyclingCheckBox.Value;
            app.Model.Recycling=value;
            app.ViewIndexTable(app.Model.wasteAnalysis); 
        end

        % Value changed function: SummaryCheckBox
        function SummaryValueChanged(app, event)
            % Activate Summary Results
            value = app.SummaryCheckBox.Value;
            app.Model.Summary=value;
            if value
                app.ActiveResults(cType.ResultId.SUMMARY_RESULTS,true);
            else
                app.ActiveResults(cType.ResultId.SUMMARY_RESULTS,false);
            end
            app.SaveSummaryMenu.Enable=value;
            app.ViewIndexTable(app.Model.summaryResults);  
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            web('https://www.exergoecology.com');
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            if isempty(app.CurrentNode)
                return
            end
            selection = app.UITable.Selection;
            idx=selection(1);
            sg=(selection(2)==cType.GRAPH_COLUMN);
            tbl=app.TableIndex.Content{idx};
            showTable(tbl,app.viewOption);
            if tbl.isGraph && sg
                graph=app.TableIndex.RowNames{idx};
                showGraph(app.CurrentNode,graph);
            end
        end

        % Value changed function: ViewTableDropDown
        function getTableViewOption(app, event)
            value = app.ViewTableDropDown.Value;
            app.viewOption=cType.getTableView(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TAES App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '  File ';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadData, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveResultsMenu = uimenu(app.SaveMenu);
            app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResults, true);
            app.SaveResultsMenu.Tooltip = {'Save  currently state results '};
            app.SaveResultsMenu.Enable = 'off';
            app.SaveResultsMenu.Text = 'Results';
            app.SaveDataModelMenu = uimenu(app.SaveMenu);
            app.SaveDataModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataModel, true);
            app.SaveDataModelMenu.Tooltip = {'Save data model, in other format.'};
            app.SaveDataModelMenu.Enable = 'off';
            app.SaveDataModelMenu.Text = 'Data Model';
            app.SaveSummaryMenu = uimenu(app.SaveMenu);
            app.SaveSummaryMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSummary, true);
            app.SaveSummaryMenu.Tooltip = {'Save the summary results'};
            app.SaveSummaryMenu.Enable = 'off';
            app.SaveSummaryMenu.Text = 'Summary';
            app.SaveDiagramMenu = uimenu(app.SaveMenu);
            app.SaveDiagramMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDiagramFP, true);
            app.SaveDiagramMenu.Tooltip = {'Save Diagram FP Info'};
            app.SaveDiagramMenu.Enable = 'off';
            app.SaveDiagramMenu.Text = 'Diagram FP';
            app.SaveModelMenu = uimenu(app.SaveMenu);
            app.SaveModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveModel, true);
            app.SaveModelMenu.Tooltip = {'Save Model in Workspace'};
            app.SaveModelMenu.Enable = 'off';
            app.SaveModelMenu.Text = 'Model';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseApp, true);
            app.CloseMenu.Text = 'Close';
            app.ResultsMenu = uimenu(app.UIFigure);
            app.ResultsMenu.Text = 'Results';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = ' Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputParametersPanel = uipanel(app.GridLayout);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.InputGridLayout = uigridlayout(app.InputParametersPanel);
            app.SaveButton = uibutton(app.InputGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResults, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Select the results file name'};
            app.SaveButton.Text = 'Save Results';
            app.LoadDataButton = uibutton(app.InputGridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Tooltip = {'Select the data model file'};
            app.LoadDataButton.Text = 'Load Data';
            app.ViewTableDropDown = uidropdown(app.InputGridLayout);
            app.ViewTableDropDown.Items = {'CONSOLE', 'GUI', 'HTML'};
            app.ViewTableDropDown.ValueChangedFcn = createCallbackFcn(app, @getTableViewOption, true);
            app.ViewTableDropDown.Enable = 'off';
            app.ViewTableDropDown.Tooltip = {'Select the tables to show'};
            app.ViewTableDropDown.Value = 'CONSOLE';
            app.OutputFileEditField = uieditfield(app.InputGridLayout, 'text');
            app.OutputFileEditField.Editable = 'off';
            app.OutputFileEditField.Tooltip = {'Show the file name to save the results'};
            app.OutputFileEditField.Value = 'ModelResults.xlsx';
            app.ResultsFileLabel = uilabel(app.InputGridLayout);
            app.ResultsFileLabel.Text = 'Results File:';
            app.SummaryCheckBox = uicheckbox(app.InputGridLayout);
            app.SummaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @SummaryValueChanged, true);
            app.SummaryCheckBox.Enable = 'off';
            app.SummaryCheckBox.Tooltip = {'Activate Summary Results'};
            app.SummaryCheckBox.Text = '';
            app.SummaryResultsLabel = uilabel(app.InputGridLayout);
            app.SummaryResultsLabel.Text = 'Summary Results:';
            app.RecyclingCheckBox = uicheckbox(app.InputGridLayout);
            app.RecyclingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecyclingValueChanged, true);
            app.RecyclingCheckBox.Enable = 'off';
            app.RecyclingCheckBox.Tooltip = {'Activate recycling analysis'};
            app.RecyclingCheckBox.Text = '';
            app.RecyclingAnalysisLabel = uilabel(app.InputGridLayout);
            app.RecyclingAnalysisLabel.Text = 'Recycling Analysis:';
            app.ActiveWasteDropDown = uidropdown(app.InputGridLayout);
            app.ActiveWasteDropDown.Items = {'NONE'};
            app.ActiveWasteDropDown.ValueChangedFcn = createCallbackFcn(app, @getWasteFlow, true);
            app.ActiveWasteDropDown.Enable = 'off';
            app.ActiveWasteDropDown.Tooltip = {'Select the active flow for recycling analysis'};
            app.ActiveWasteDropDown.Value = 'NONE';
            app.ActiveWasteDropDownLabel = uilabel(app.InputGridLayout);
            app.ActiveWasteDropDownLabel.Text = 'Active Waste:';
            app.DataModelEditFieldLabel = uilabel(app.InputGridLayout);
            app.DataModelEditFieldLabel.Text = 'Data Model:';
            app.DataModelEditField = uieditfield(app.InputGridLayout, 'text');
            app.DataModelEditField.Editable = 'off';
            app.DataModelEditField.Tooltip = {'Show the data model file'};
            app.DataModelEditField.Value = 'No Model Available';
            app.OperationStateDropDownLabel = uilabel(app.InputGridLayout);
            app.OperationStateDropDownLabel.Text = 'Operation State:';
            app.OperationStateDropDown = uidropdown(app.InputGridLayout);
            app.OperationStateDropDown.Items = {'Reference', 'Operation'};
            app.OperationStateDropDown.ValueChangedFcn = createCallbackFcn(app, @getOperationState, true);
            app.OperationStateDropDown.Enable = 'off';
            app.OperationStateDropDown.Tooltip = {'Select the operation state'};
            app.OperationStateDropDown.Value = 'Reference';
            app.ResourceCostDropDownLabel = uilabel(app.InputGridLayout);
            app.ResourceCostDropDownLabel.Text = 'Resource Cost:';
            app.ResourceCostDropDown = uidropdown(app.InputGridLayout);
            app.ResourceCostDropDown.Items = {'Base'};
            app.ResourceCostDropDown.ValueChangedFcn = createCallbackFcn(app, @getResourcesSample, true);
            app.ResourceCostDropDown.Enable = 'off';
            app.ResourceCostDropDown.Tooltip = {'Select the resources cost sample'};
            app.ResourceCostDropDown.Value = 'Base';
            app.CostTablesDropDownLabel = uilabel(app.InputGridLayout);
            app.CostTablesDropDownLabel.Text = 'Cost Tables:';
            app.CostTablesDropDown = uidropdown(app.InputGridLayout);
            app.CostTablesDropDown.Items = {'DIRECT'};
            app.CostTablesDropDown.ValueChangedFcn = createCallbackFcn(app, @getCostTables, true);
            app.CostTablesDropDown.Enable = 'off';
            app.CostTablesDropDown.Tooltip = {'Select the tables to show'};
            app.CostTablesDropDown.Value = 'DIRECT';
            app.DiagnosisMethodDropDownLabel = uilabel(app.InputGridLayout);
            app.DiagnosisMethodDropDownLabel.Text = 'Diagnosis Method:';
            app.DiagnosisMethodDropDown = uidropdown(app.InputGridLayout);
            app.DiagnosisMethodDropDown.Items = {'NONE'};
            app.DiagnosisMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @getDiagnosisMethod, true);
            app.DiagnosisMethodDropDown.Enable = 'off';
            app.DiagnosisMethodDropDown.Tooltip = {'Select the diagnosis method to use'};
            app.DiagnosisMethodDropDown.Value = 'NONE';
            app.ReferenceStateDropDownLabel = uilabel(app.InputGridLayout);
            app.ReferenceStateDropDownLabel.Text = 'Reference State:';
            app.ReferenceStateDropDown = uidropdown(app.InputGridLayout);
            app.ReferenceStateDropDown.Items = {'Reference'};
            app.ReferenceStateDropDown.ValueChangedFcn = createCallbackFcn(app, @getReferenceState, true);
            app.ReferenceStateDropDown.Enable = 'off';
            app.ReferenceStateDropDown.Tooltip = {'Select Reference State'};
            app.ReferenceStateDropDown.Value = 'Reference';
            app.ViewTableDropDownLabel = uilabel(app.InputGridLayout);
            app.ViewTableDropDownLabel.Text = 'View Table:';
            app.TableIndexPanel = uipanel(app.GridLayout);
            app.TableIndexPanel.Title = 'Table Index';
            app.UITable = uitable(app.TableIndexPanel);
            app.UITable.ColumnName = {'Table'; 'Description'; 'Graph'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.LogPanel = uipanel(app.GridLayout);
            app.Hyperlink = uihyperlink(app.LogPanel);
            app.Hyperlink.URL = 'https://www.exergoecology.com';
            app.Hyperlink.Text = 'www.exergocology.com';
            app.LogField = uilabel(app.LogPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaesTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end