classdef prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        UITable          matlab.ui.control.Table
        ShowGraphButton  matlab.ui.control.Button
        ShowTableButton  matlab.ui.control.Button
        Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Tables % Description
        ActiveTable % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, res)
            log=cStatus();
            if nargin~=2
                log.printError('Values to show are required');
                log.printError('Usage: ViewModelResuls(res)');
                delete(app);
                return
            end
            if isa(res,'cResultInfo')
                val=res;
            elseif isa(res,'cThermoeconomicModel')
                val=res.getModelInfo;
            else
                log.printError('Results must be a cResultInfo object');
                delete(app);
                return
            end
            app.Label.Text=val.ResultName;
            tbl=val.getTableIndex;
            app.UITable.RowName = tbl.RowNames;
            app.UITable.ColumnName = tbl.ColNames{2};
            app.UITable.ColumnWidth={'auto','auto'};
            app.UITable.ColumnFormat={'char','char'};
            app.UITable.Data=tbl.Data;
            app.Label.Visible=true;
            app.UITable.Visible=true;
            app.Tables=tbl.Content;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.ActiveTable=app.Tables{indices(1)};
            app.ShowTableButton.Enable="on";
            if isGraph(app.ActiveTable)
                app.ShowGraphButton.Enable="on";
            else
                app.ShowGraphButton.Enable="off";
            end
        end

        % Button pushed function: ShowTableButton
        function ShowTableButtonPushed(app, event)
            viewHTML(app.ActiveTable);
        end

        % Button pushed function: ShowGraphButton
        function ShowGraphButtonPushed(app, event)
            showGraph(app.ActiveTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.ShowTableButton = uibutton(app.GridLayout, 'push');
            app.ShowTableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTableButtonPushed, true);
            app.ShowTableButton.Enable = 'off';
            app.ShowTableButton.Text = 'Show Table';
            app.ShowGraphButton = uibutton(app.GridLayout, 'push');
            app.ShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGraphButtonPushed, true);
            app.ShowGraphButton.Enable = 'off';
            app.ShowGraphButton.Text = 'Show Graph';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Table'; 'Description'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end